classdef INTERFAZ_INTERPOLACION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReconstruccionSINCXButton  matlab.ui.control.Button
        ReconstruccionfirstorderholdButton  matlab.ui.control.Button
        ReconstruccionzeroorderholdButton  matlab.ui.control.Button
        ReconstruccionnearstneighborButton  matlab.ui.control.Button
        CargarAudioButton          matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       
        SignalData % Datos de la señal cargada
        fs % Frecuencia de muestreo de la señal
        numSamples = 10  % Número de muestras para visualización y reconstrucción
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarAudioButton
        function CargarAudioButtonPushed(app, event)
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo WAV');
            if isequal(file,0)
                return; % Si no selecciona archivo, termina
            end
            [app.SignalData, app.fs] = audioread(fullfile(path, file));

            % Muestra las primeras 10 muestras en UIAxes
            t_muestras = (0:app.numSamples-1) * (1 / app.fs);
            muestras = app.SignalData(1:app.numSamples);
            stem(app.UIAxes, t_muestras, muestras, 'filled', 'LineWidth', 1.5);
            title(app.UIAxes, 'Primeras 10 muestras de la señal');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            grid(app.UIAxes, 'on')

        end

        % Button pushed function: ReconstruccionnearstneighborButton
        function ReconstruccionnearstneighborButtonPushed(app, event)
            t_muestras = (0:app.numSamples-1) * (1 / app.fs);
            muestras = app.SignalData(1:app.numSamples);
            t_total = linspace(0, (app.numSamples-1) * (1 / app.fs), 1000);
            reconstruccion = interp1(t_muestras, muestras, t_total, 'nearest');
            
            % Muestra la reconstrucción en UIAxes
            stem(app.UIAxes, t_muestras, muestras, 'filled', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, t_total, reconstruccion, 'b', 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Reconstrucción con Nearest Neighbor');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            grid(app.UIAxes, 'on');

            % Forma del filtro en UIAxes2
            t = -1:0.01:1;
            T = 0.5;
            nearest_filter = double(abs(t) <= T/2);
            plot(app.UIAxes2, t, nearest_filter, 'b', 'LineWidth', 1.5);
            title(app.UIAxes2, 'Forma del Filtro Nearest Neighbor');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud');
            ylim(app.UIAxes2, [-0.2, 1.2]);
            xlim(app.UIAxes2, [-1, 1]);
            grid(app.UIAxes2, 'on');
            
        end

        % Button pushed function: ReconstruccionzeroorderholdButton
        function ReconstruccionzeroorderholdButtonPushed(app, event)
            t_muestras = (0:app.numSamples-1) * (1 / app.fs);
            muestras = app.SignalData(1:app.numSamples);
            t_total = linspace(0, (app.numSamples-1) * (1 / app.fs), 1000);
            reconstruccion = interp1(t_muestras, muestras, t_total, 'previous');

            % Muestra la reconstrucción en UIAxes
            stem(app.UIAxes, t_muestras, muestras, 'filled', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, t_total, reconstruccion, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Reconstrucción con Zero-Order Hold');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            grid(app.UIAxes, 'on');

            % Forma del filtro en UIAxes2
            t = -1:0.01:1;
            T = 0.5;
            zero_order_filter = double((t >= 0) & (t < T));
            plot(app.UIAxes2, t, zero_order_filter, 'r', 'LineWidth', 1.5);
            title(app.UIAxes2, 'Forma del Filtro Zero-Order Hold');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud');
            ylim(app.UIAxes2, [-0.2, 1.2]);
            xlim(app.UIAxes2, [-1, 1]);
            grid(app.UIAxes2, 'on');
            
        end

        % Button pushed function: ReconstruccionfirstorderholdButton
        function ReconstruccionfirstorderholdButtonPushed(app, event)
            t_muestras = (0:app.numSamples-1) * (1 / app.fs);
            muestras = app.SignalData(1:app.numSamples);
            t_total = linspace(0, (app.numSamples-1) * (1 / app.fs), 1000);
            reconstruccion = interp1(t_muestras, muestras, t_total, 'linear');

            % Muestra la reconstrucción en UIAxes
            stem(app.UIAxes, t_muestras, muestras, 'filled', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, t_total, reconstruccion, 'g', 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Reconstrucción con First-Order Hold');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            grid(app.UIAxes, 'on');

            % Forma del filtro en UIAxes2
            t = -1:0.01:1;
            T = 0.5;
            first_order_filter = max(0, 1 - abs(t) / T);
            plot(app.UIAxes2, t, first_order_filter, 'g', 'LineWidth', 1.5);
            title(app.UIAxes2, 'Forma del Filtro First-Order Hold');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud');
            ylim(app.UIAxes2, [-0.2, 1.2]);
            xlim(app.UIAxes2, [-1, 1]);
            grid(app.UIAxes2, 'on');
            
        end

        % Button pushed function: ReconstruccionSINCXButton
        function ReconstruccionSINCXButtonPushed(app, event)
            t_muestras = (0:app.numSamples-1) * (1 / app.fs); % Tiempo de cada muestra
            muestras = app.SignalData(1:app.numSamples); % Primeras 10 muestras de la señal
            t_total = linspace(0, (app.numSamples-1) * (1 / app.fs), 1000); % Tiempo para reconstrucción completa
            
            % Inicializar la reconstrucción total
            reconstruccion_sinc = zeros(1, length(t_total));
            
            % Limpiar y mostrar las muestras originales en el gráfico
            cla(app.UIAxes);
            stem(app.UIAxes, t_muestras, muestras, 'filled', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            
            % Iterar sobre cada muestra para mostrar su contribución con la función sinc
            for k = 1:app.numSamples
                sinc_component = muestras(k) * sinc((t_total - (k-1)*(1 / app.fs)) / (1 / app.fs));
                plot(app.UIAxes, t_total, sinc_component, '--', 'LineWidth', 1); % Mostrar sinc individual
                reconstruccion_sinc = reconstruccion_sinc + sinc_component; % Sumar la contribución
            end
            
            % Mostrar la reconstrucción final con la suma de todas las sinc
            plot(app.UIAxes, t_total, reconstruccion_sinc, 'm', 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Reconstrucción con sinc (Mostrando contribución de cada función sinc)');
            xlabel(app.UIAxes, 'Tiempo (s)');
            ylabel(app.UIAxes, 'Amplitud');
            grid(app.UIAxes, 'on');
            
            % Forma del filtro sinc en UIAxes2
            t = -1:0.01:1;
            T = 0.5;
            sinc_filter = sinc(t / T);
            plot(app.UIAxes2, t, sinc_filter, 'm', 'LineWidth', 1.5);
            title(app.UIAxes2, 'Forma del Filtro sinc');
            xlabel(app.UIAxes2, 'Tiempo (s)');
            ylabel(app.UIAxes2, 'Amplitud');
            ylim(app.UIAxes2, [-0.3, 1.2]);
            xlim(app.UIAxes2, [-1, 1]);
            grid(app.UIAxes2, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CargarAudioButton = uibutton(app.UIFigure, 'push');
            app.CargarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudioButton.Text = 'Cargar Audio';
            app.ReconstruccionnearstneighborButton = uibutton(app.UIFigure, 'push');
            app.ReconstruccionnearstneighborButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstruccionnearstneighborButtonPushed, true);
            app.ReconstruccionnearstneighborButton.Text = 'Reconstruccion nearst neighbor';
            app.ReconstruccionzeroorderholdButton = uibutton(app.UIFigure, 'push');
            app.ReconstruccionzeroorderholdButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstruccionzeroorderholdButtonPushed, true);
            app.ReconstruccionzeroorderholdButton.Text = 'Reconstruccion zero order hold';
            app.ReconstruccionfirstorderholdButton = uibutton(app.UIFigure, 'push');
            app.ReconstruccionfirstorderholdButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstruccionfirstorderholdButtonPushed, true);
            app.ReconstruccionfirstorderholdButton.Text = 'Reconstruccion first order hold';
            app.ReconstruccionSINCXButton = uibutton(app.UIFigure, 'push');
            app.ReconstruccionSINCXButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstruccionSINCXButtonPushed, true);
            app.ReconstruccionSINCXButton.Text = 'Reconstruccion SINC(X)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTERFAZ_INTERPOLACION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end