classdef Z_PLANE_INTERFACE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MagnitudeSlider   matlab.ui.control.Slider
        ANGULOLabel       matlab.ui.control.Label
        DenominadorField  matlab.ui.control.EditField
        DENOMIADORLabel   matlab.ui.control.Label
        NumeradorField    matlab.ui.control.EditField
        NUMERADORLabel    matlab.ui.control.Label
        CalcularButton    matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    ceros   % Propiedad para almacenar los ceros de la función de transferencia
    polos   % Propiedad para almacenar los polos de la función de transferencia
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
 
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % Obtener los valores de los campos de entrada como cadenas de texto
            numerador_str = app.NumeradorField.Value;
            denominador_str = app.DenominadorField.Value;
        
            % Convertir las cadenas de texto en arreglos numéricos
            numerador = str2num(numerador_str); % Convertir entrada a array numérico
            denominador = str2num(denominador_str); % Convertir entrada a array numérico
            
            % Validar que los campos no estén vacíos y que no contengan NaN o Inf
            if isempty(numerador) || isempty(denominador)
                uialert(app.UIFigure, 'Por favor ingrese los coeficientes de numerador y denominador como números separados por comas.', 'Error');
                return;
            elseif any(isnan(numerador)) || any(isinf(numerador)) || any(isnan(denominador)) || any(isinf(denominador))
                uialert(app.UIFigure, 'Los coeficientes no deben contener valores NaN o Inf.', 'Error');
                return;
            end
            
            % Calcular y almacenar ceros y polos en las propiedades de la app
            app.ceros = roots(numerador);
            app.polos = roots(denominador);
        
            % Graficar los polos y ceros en el plano Z en UIAxes2
            cla(app.UIAxes2); % Limpiar UIAxes2 antes de graficar
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, real(app.ceros), imag(app.ceros), 'go', 'MarkerSize', 8, 'LineWidth', 1.5); % Ceros en verde (O)
            plot(app.UIAxes2, real(app.polos), imag(app.polos), 'rx', 'MarkerSize', 8, 'LineWidth', 1.5); % Polos en rojo (X)
        
            % Dibujar la circunferencia unitaria
            theta = linspace(0, 2*pi, 100);
            plot(app.UIAxes2, cos(theta), sin(theta), 'b--'); % Circunferencia unitaria en línea azul discontinua
            title(app.UIAxes2, 'Plano Z - Polos y Ceros');
            xlabel(app.UIAxes2, 'Parte Real');
            ylabel(app.UIAxes2, 'Parte Imaginaria');
            axis(app.UIAxes2, 'equal');
            grid(app.UIAxes2, 'on');
            hold(app.UIAxes2, 'off');
        
            % Calcular y graficar la magnitud en función de la frecuencia angular en UIAxes
            frecuencias = linspace(0, 2 * pi, 1000);
            magnitudes = zeros(1, length(frecuencias));
            for k = 1:length(frecuencias)
                z = exp(1i * frecuencias(k));
                producto_ceros = prod(abs(app.ceros - z));
                producto_polos = prod(abs(app.polos - z));
                magnitudes(k) = producto_ceros / producto_polos;
            end
            cla(app.UIAxes); % Limpiar UIAxes antes de graficar
            plot(app.UIAxes, frecuencias, magnitudes, 'b', 'LineWidth', 1.5); % Graficar la curva de magnitud
            title(app.UIAxes, 'Magnitud |H(\omega)| en función de \omega');
            xlabel(app.UIAxes, 'Frecuencia Angular \omega [rad/s]');
            ylabel(app.UIAxes, 'Magnitud |H(\omega)|');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [0, 2 * pi]);
        
            % Establecer los ticks del eje X en intervalos de π/4 y etiquetar en términos de π
            xticks = [0, pi/4, pi/2, 3*pi/4, pi, 5*pi/4, 3*pi/2, 7*pi/4, 2*pi];
            xticklabels = {'0', '\pi/4', '\pi/2', '3\pi/4', '\pi', '5\pi/4', '3\pi/2', '7\pi/4', '2\pi'};
            set(app.UIAxes, 'XTick', xticks, 'XTickLabel', xticklabels); % Aplicar los xticks y xticklabels
        
         
        end

        % Value changed function: MagnitudeSlider
        function MagnitudeSliderValueChanged(app, event)
                    % Obtener el valor actual del slider en radianes
                    omega = event.Value;
                
                    % Calcular el valor de la magnitud en el ángulo omega
                    z = exp(1i * omega); % Punto en la circunferencia unitaria correspondiente a omega
                    producto_ceros = prod(abs(app.ceros - z));
                    producto_polos = prod(abs(app.polos - z));
                    magnitud = producto_ceros / producto_polos;
                
                    % Volver a dibujar la curva de magnitud y añadir el marcador rojo
                    hold(app.UIAxes, 'off'); % Liberar el hold para actualizar la gráfica
                    frecuencias = linspace(0, 2 * pi, 1000);
                    magnitudes = zeros(1, length(frecuencias));
                    for k = 1:length(frecuencias)
                        z_freq = exp(1i * frecuencias(k));
                        producto_ceros_freq = prod(abs(app.ceros - z_freq));
                        producto_polos_freq = prod(abs(app.polos - z_freq));
                        magnitudes(k) = producto_ceros_freq / producto_polos_freq;
                    end
                    plot(app.UIAxes, frecuencias, magnitudes, 'b', 'LineWidth', 1.5); % Gráfica de la curva completa
                    hold(app.UIAxes, 'on'); % Mantener el hold para añadir el marcador
                    plot(app.UIAxes, omega, magnitud, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r'); % Añadir el marcador rojo
                    hold(app.UIAxes, 'off');
                
                    % Configurar el título y etiquetas del gráfico
                    title(app.UIAxes, 'Magnitud |H(\omega)| en función de \omega');
                    xlabel(app.UIAxes, 'Frecuencia Angular \omega [rad/s]');
                    ylabel(app.UIAxes, 'Magnitud |H(\omega)|');
                    grid(app.UIAxes, 'on');
                    xlim(app.UIAxes, [0, 2 * pi]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'CALCULAR';
            app.NUMERADORLabel = uilabel(app.UIFigure);
            app.NUMERADORLabel.Text = 'NUMERADOR';
            app.NumeradorField = uieditfield(app.UIFigure, 'text');
            app.DENOMIADORLabel = uilabel(app.UIFigure);
            app.DENOMIADORLabel.Text = 'DENOMIADOR';
            app.DenominadorField = uieditfield(app.UIFigure, 'text');
            app.ANGULOLabel = uilabel(app.UIFigure);
            app.ANGULOLabel.Text = 'FRECUENCIA ANGULAR [W]';
            app.MagnitudeSlider = uislider(app.UIFigure);
            app.MagnitudeSlider.Limits = [0 6.2832];
            app.MagnitudeSlider.MajorTicks = [0 0.2618 0.5236 0.7854 1.0472 1.309 1.5708 1.8326 2.0944 2.3562 2.618 2.8798 3.1416 3.4034 3.6652 3.927 4.1888 4.4506 4.7124 4.9742 5.236 5.4978 5.7596 6.0214 6.2832];
            app.MagnitudeSlider.MajorTickLabels = {'0', 'π/12', 'π/6', 'π/4', 'π/3', '5π/12', 'π/2', '7π/12', '2π/3', '3π/4', '5π/6', '11π/12', 'π', '13π/12', '7π/6', '5π/4', '4π/3', '17π/12', '3π/2', '19π/12', '5π/3', '7π/4', '11π/6', '23π/12', '2π'};
            app.MagnitudeSlider.ValueChangedFcn = createCallbackFcn(app, @MagnitudeSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z_PLANE_INTERFACE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end